classdef initialisePopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SortinginitialiserUIFigure    matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataoptionsTab                matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        BytespersampleEditFieldLabel  matlab.ui.control.Label
        BytespersampleEditField       matlab.ui.control.NumericEditField
        ofchannelsEditFieldLabel      matlab.ui.control.Label
        nchEditField                  matlab.ui.control.NumericEditField
        MainchanelnumberSpinnerLabel  matlab.ui.control.Label
        MainchannelnumberSpinner      matlab.ui.control.Spinner
        SamplingrateHzEditFieldLabel  matlab.ui.control.Label
        SamplingrateHzEditField       matlab.ui.control.NumericEditField
        SpikewidtheithersideofpeakmsLabel  matlab.ui.control.Label
        SpikewidthEditField           matlab.ui.control.NumericEditField
        DuplexdataCheckBox            matlab.ui.control.CheckBox
        ChoosebinButton               matlab.ui.control.Button
        FileLabel                     matlab.ui.control.Label
        LengthofloadeddatablocksamplesEditFieldLabel  matlab.ui.control.Label
        SplitlengthEditField          matlab.ui.control.NumericEditField
        SimpleamplitudeThresholduVEditFieldLabel  matlab.ui.control.Label
        AmpthresholdEditField         matlab.ui.control.NumericEditField
        NewtemplatethresholdhigherlenientEditFieldLabel  matlab.ui.control.Label
        NewtemplateEditField          matlab.ui.control.NumericEditField
        AddtotemplatethresholdhigherlenientEditFieldLabel  matlab.ui.control.Label
        AddtotemplateEditField        matlab.ui.control.NumericEditField
        SuffixtoputonsaveddataEditFieldLabel  matlab.ui.control.Label
        SuffixEditField               matlab.ui.control.EditField
        EphyschannelsarrayformatEditFieldLabel  matlab.ui.control.Label
        echEditField                  matlab.ui.control.EditField
        MultiplieronyscaleyaxisisinuVLabel  matlab.ui.control.Label
        ScaleEditField                matlab.ui.control.NumericEditField
        FilteringoptionsTab           matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        FilterlengthinsamplesEditFieldLabel  matlab.ui.control.Label
        FilterLengthEditField         matlab.ui.control.NumericEditField
        FilterlowpasscutoffEditFieldLabel  matlab.ui.control.Label
        LowpassEditField              matlab.ui.control.NumericEditField
        FilterhighpasscutoffEditFieldLabel  matlab.ui.control.Label
        HighpassEditField             matlab.ui.control.NumericEditField
        InitialiseButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        initm
        initt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.InitialiseButton.Enable = 'off';
        end

        % Button pushed function: ChoosebinButton
        function ChoosebinButtonPushed(app, event)
            [app.initm.fN, app.initm.fP] = uigetfile('*.bin');
            if app.initm.fN ~= 0
                fid = fopen([app.initm.fP app.initm.fN],'r');
                
                if fid > 0
                    fseek(fid,0,'eof');
                    app.initm.fileSizeBytes = ftell(fid);
                    fclose(fid);
                    
                    app.FileLabel.Text = app.initm.fN;
                    app.FileLabel.FontColor = [0 0 0];
                    app.InitialiseButton.Enable = 'on';
                else
                    fclose(fid);
                    app.FileLabel.Text = "Could not open file!";
                    app.FileLabel.FontColor = [255 0 0];
                end
            end
            app.SortinginitialiserUIFigure.Visible = 'off';
            app.SortinginitialiserUIFigure.Visible = 'on';
            
            
        end

        % Close request function: SortinginitialiserUIFigure
        function SortinginitialiserUIFigureCloseRequest(app, event)
            switchButtons(app.CallingApp,1);
            
            % Delete the dialog box
            delete(app)
            
        end

        % Button pushed function: InitialiseButton
        function OKButtonPushed(app, event)
            switchButtons(app.CallingApp,0);
            
            app.initm.dbytes = app.BytespersampleEditField.Value;
            app.initm.nChans = app.nchEditField.Value;
            if ~isempty(app.echEditField.Value)
                app.initm.ech = eval(app.echEditField.Value);
                if iscolumn(app.initm.ech)
                    app.initm.ech = app.initm.ech';
                end
            else
                app.initm.ech = [];
            end
            app.initm.mainCh = find(app.initm.ech == round(app.MainchannelnumberSpinner.Value));
            app.initm.sRateHz = app.SamplingrateHzEditField.Value;
            app.initm.el_flen = app.FilterLengthEditField.Value;
            app.initm.el_cutH = app.HighpassEditField.Value;
            app.initm.el_cutL = app.LowpassEditField.Value;
            if app.DuplexdataCheckBox.Value
                app.initm.sRateHz = 2*app.SamplingrateHzEditField.Value;
            else
                app.initm.sRateHz = app.SamplingrateHzEditField.Value;
            end
            app.initm.spikeWidth = round(app.SpikewidthEditField.Value*app.initm.sRateHz/1000);
            
            app.initt.add2UnitThr(1) = app.NewtemplateEditField.Value;
            app.initt.add2UnitThr(2) = app.AddtotemplateEditField.Value;
            app.initt.saveNameSuffix = app.SuffixEditField.Value;
            app.initt.yscale = app.ScaleEditField.Value;
            app.initt.batchSize = app.SplitlengthEditField.Value;
            app.initt.detectThr = app.AmpthresholdEditField.Value;
            
            app.InitialiseButton.Enable = "off";
            updateVars(app.CallingApp, app.initm, app.initt, []);
            switchButtons(app.CallingApp,1);
            
            delete(app)
        end

        % Value changed function: nchEditField
        function nchEditFieldValueChanged(app, event)
            value = app.nchEditField.Value;
            app.MainchannelnumberSpinner.Limits = [1 value+0.1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SortinginitialiserUIFigure = uifigure('Visible', 'off');
            app.SortinginitialiserUIFigure.Name = 'Sorting initialiser';
            app.SortinginitialiserUIFigure.CloseRequestFcn = createCallbackFcn(app, @SortinginitialiserUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SortinginitialiserUIFigure);
            app.DataoptionsTab = uitab(app.TabGroup);
            app.DataoptionsTab.Title = 'Data options';
            app.GridLayout2 = uigridlayout(app.DataoptionsTab);
            app.BytespersampleEditFieldLabel = uilabel(app.GridLayout2);
            app.BytespersampleEditFieldLabel.Text = 'Bytes per sample';
            app.BytespersampleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BytespersampleEditField.Value = 2;
            app.ofchannelsEditFieldLabel = uilabel(app.GridLayout2);
            app.ofchannelsEditFieldLabel.Text = '# of channels';
            app.nchEditField = uieditfield(app.GridLayout2, 'numeric');
            app.nchEditField.ValueChangedFcn = createCallbackFcn(app, @nchEditFieldValueChanged, true);
            app.nchEditField.Value = 4;
            app.MainchanelnumberSpinnerLabel = uilabel(app.GridLayout2);
            app.MainchanelnumberSpinnerLabel.Text = 'Main chanel number';
            app.MainchannelnumberSpinner = uispinner(app.GridLayout2);
            app.MainchannelnumberSpinner.Limits = [1 4.1];
            app.MainchannelnumberSpinner.Value = 1;
            app.SamplingrateHzEditFieldLabel = uilabel(app.GridLayout2);
            app.SamplingrateHzEditFieldLabel.Text = 'Sampling rate (Hz)';
            app.SamplingrateHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SamplingrateHzEditField.Value = 30000;
            app.SpikewidtheithersideofpeakmsLabel = uilabel(app.GridLayout2);
            app.SpikewidtheithersideofpeakmsLabel.Text = 'Spike width either side of peak (ms)';
            app.SpikewidthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpikewidthEditField.Value = 0.67;
            app.DuplexdataCheckBox = uicheckbox(app.GridLayout2);
            app.DuplexdataCheckBox.Text = 'Duplex data?';
            app.ChoosebinButton = uibutton(app.GridLayout2, 'push');
            app.ChoosebinButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosebinButtonPushed, true);
            app.ChoosebinButton.Text = 'Choose .bin';
            app.FileLabel = uilabel(app.GridLayout2);
            app.FileLabel.Text = 'None';
            app.LengthofloadeddatablocksamplesEditFieldLabel = uilabel(app.GridLayout2);
            app.LengthofloadeddatablocksamplesEditFieldLabel.Text = 'Length of loaded data block (samples)';
            app.SplitlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SplitlengthEditField.Value = 6000000;
            app.SimpleamplitudeThresholduVEditFieldLabel = uilabel(app.GridLayout2);
            app.SimpleamplitudeThresholduVEditFieldLabel.Text = 'Simple amplitude Threshold (uV)';
            app.AmpthresholdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AmpthresholdEditField.Value = -80;
            app.NewtemplatethresholdhigherlenientEditFieldLabel = uilabel(app.GridLayout2);
            app.NewtemplatethresholdhigherlenientEditFieldLabel.Text = 'New template threshold (higher = lenient)';
            app.NewtemplateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NewtemplateEditField.Value = 6;
            app.AddtotemplatethresholdhigherlenientEditFieldLabel = uilabel(app.GridLayout2);
            app.AddtotemplatethresholdhigherlenientEditFieldLabel.Text = 'Add to template threshold (higher = lenient)';
            app.AddtotemplateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AddtotemplateEditField.Value = 6;
            app.SuffixtoputonsaveddataEditFieldLabel = uilabel(app.GridLayout2);
            app.SuffixtoputonsaveddataEditFieldLabel.Text = 'Suffix to put on saved data';
            app.SuffixEditField = uieditfield(app.GridLayout2, 'text');
            app.EphyschannelsarrayformatEditFieldLabel = uilabel(app.GridLayout2);
            app.EphyschannelsarrayformatEditFieldLabel.Text = 'Ephys channels (array format)';
            app.echEditField = uieditfield(app.GridLayout2, 'text');
            app.echEditField.Value = '[1]';
            app.MultiplieronyscaleyaxisisinuVLabel = uilabel(app.GridLayout2);
            app.MultiplieronyscaleyaxisisinuVLabel.Text = 'Multiplier on y-scale (y-axis is in uV)';
            app.ScaleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ScaleEditField.Value = 1;
            app.FilteringoptionsTab = uitab(app.TabGroup);
            app.FilteringoptionsTab.Title = 'Filtering options';
            app.GridLayout = uigridlayout(app.FilteringoptionsTab);
            app.FilterlengthinsamplesEditFieldLabel = uilabel(app.GridLayout);
            app.FilterlengthinsamplesEditFieldLabel.Text = 'Filter length in samples';
            app.FilterLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FilterLengthEditField.Value = 400;
            app.FilterlowpasscutoffEditFieldLabel = uilabel(app.GridLayout);
            app.FilterlowpasscutoffEditFieldLabel.Text = 'Filter lowpass cutoff';
            app.LowpassEditField = uieditfield(app.GridLayout, 'numeric');
            app.LowpassEditField.Value = 7000;
            app.FilterhighpasscutoffEditFieldLabel = uilabel(app.GridLayout);
            app.FilterhighpasscutoffEditFieldLabel.Text = 'Filter highpass cutoff';
            app.HighpassEditField = uieditfield(app.GridLayout, 'numeric');
            app.HighpassEditField.Value = 700;
            app.InitialiseButton = uibutton(app.SortinginitialiserUIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            % Show the figure after all components are created
            app.SortinginitialiserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initialisePopup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SortinginitialiserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SortinginitialiserUIFigure)
        end
    end
end